classdef BladeDesignerBeta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BladeDesigner                  matlab.ui.Figure
        BladeDesignerBetaVersionLabel  matlab.ui.control.Label
        Selection                      matlab.ui.container.TabGroup
        Turbine                        matlab.ui.container.Tab
        CamberAxes_T                   matlab.ui.control.UIAxes
        ThicknessAxes_T                matlab.ui.control.UIAxes
        BladeAxes_T                    matlab.ui.control.UIAxes
        InletMetalAngleSlider_T        matlab.ui.control.Slider
        OutletMetalAngleSliderLabel_T  matlab.ui.control.Label
        OutletMetalAngleSlider_T       matlab.ui.control.Slider
        InletMetalAngleSliderLabel_T   matlab.ui.control.Label
        StaggerAngleSlider_T           matlab.ui.control.Slider
        StaggerAngleSliderLabel_T      matlab.ui.control.Label
        ChoosebladethicknessprofileLabel_T  matlab.ui.control.Label
        LogdetailsLabel_T              matlab.ui.control.Label
        ThicknessOptions_T             matlab.ui.control.ListBox
        InletMetalAngleValue_T         matlab.ui.control.EditField
        OutletMetalAngleValue_T        matlab.ui.control.EditField
        StaggerAngleValue_T            matlab.ui.control.EditField
        AddthickprofileButton_T        matlab.ui.control.Button
        CamberLineAnglesLabel_T        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Camber1Value_T                 matlab.ui.control.EditField
        Label_3                        matlab.ui.control.Label
        Camber2Value_T                 matlab.ui.control.EditField
        Label_2                        matlab.ui.control.Label
        CamberAngleValue_T             matlab.ui.control.EditField
        Label_4                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        DeleteprofileButton_T          matlab.ui.control.Button
        IncreaseordecreasethemaximumthicknessLabel_T  matlab.ui.control.Label
        MaximumThicknessoftheProfileLabel  matlab.ui.control.Label
        MaximumThicknessProfile_T      matlab.ui.control.NumericEditField
        MaxThicknessSlider_T           matlab.ui.control.Slider
        MaxThicknessValue_T            matlab.ui.control.NumericEditField
        MaxThicknessSliderValue_T      matlab.ui.control.EditField
        Log_T                          matlab.ui.control.TextArea
        CompressorTab                  matlab.ui.container.Tab
        CamberAxes_C                   matlab.ui.control.UIAxes
        ThicknessAxes_C                matlab.ui.control.UIAxes
        BladeAxes_C                    matlab.ui.control.UIAxes
        InletMetalAngleSlider_C        matlab.ui.control.Slider
        OutletMetalAngleSliderLabel_C  matlab.ui.control.Label
        OutletMetalAngleSlider_C       matlab.ui.control.Slider
        InletMetalAngleSliderLabel_C   matlab.ui.control.Label
        StaggerAngleSlider_C           matlab.ui.control.Slider
        StaggerAngleSliderLabel_C      matlab.ui.control.Label
        InletMetalAngleValue_C         matlab.ui.control.EditField
        OutletMetalAngleValue_C        matlab.ui.control.EditField
        StaggerAngleValue_C            matlab.ui.control.EditField
        CamberLineAnglesLabel_C        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Camber1Value_C                 matlab.ui.control.EditField
        Label_7                        matlab.ui.control.Label
        Camber2Value_C                 matlab.ui.control.EditField
        Label_8                        matlab.ui.control.Label
        CamberAngleValue_C             matlab.ui.control.EditField
        ChoosebladethicknessprofileLabel_C  matlab.ui.control.Label
        ThicknessOptions_C             matlab.ui.control.ListBox
        AddthickprofileButton_C        matlab.ui.control.Button
        DeleteprofileButton_C          matlab.ui.control.Button
        IncreaseordecreasethemaximumthicknessLabel_C  matlab.ui.control.Label
        MaximumThicknessoftheProfileLabel_C  matlab.ui.control.Label
        MaximumThicknessProfile_C      matlab.ui.control.NumericEditField
        MaxThicknessSlider_C           matlab.ui.control.Slider
        MaxThicknessValue_C            matlab.ui.control.NumericEditField
        MaxThicknessSliderValue_C      matlab.ui.control.EditField
        LogdetailsLabel_C              matlab.ui.control.Label
        Log_C                          matlab.ui.control.TextArea
        Label_9                        matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        ManualTab                      matlab.ui.container.Tab
        LeadingEdgeParametersLabel     matlab.ui.control.Label
        SemimajoraxisSliderLabel       matlab.ui.control.Label
        SemimajoraxisSlider            matlab.ui.control.Slider
        SemiminoraxisSliderLabel       matlab.ui.control.Label
        SemiminoraxisSlider            matlab.ui.control.Slider
        InletMetalSliderLabel          matlab.ui.control.Label
        InletMetalSlider               matlab.ui.control.Slider
        SSangleSliderLabel             matlab.ui.control.Label
        SSangleSlider                  matlab.ui.control.Slider
        PSangleSliderLabel             matlab.ui.control.Label
        PSangleSlider                  matlab.ui.control.Slider
        TrailingEdgeParametersLabel    matlab.ui.control.Label
        InletMetalSliderLabel_2        matlab.ui.control.Label
        InletMetalSlider_2             matlab.ui.control.Slider
        SSangleSliderLabel_2           matlab.ui.control.Label
        SSangleSlider_2                matlab.ui.control.Slider
        PSangleSliderLabel_2           matlab.ui.control.Label
        PSangleSlider_2                matlab.ui.control.Slider
        SemimajoraxisSliderLabel_2     matlab.ui.control.Label
        SemimajoraxisSlider_2          matlab.ui.control.Slider
        ProfileparametersLabel         matlab.ui.control.Label
        StaggerSliderLabel             matlab.ui.control.Label
        StaggerSlider                  matlab.ui.control.Slider
        scSliderLabel                  matlab.ui.control.Label
        scSlider                       matlab.ui.control.Slider
        ocSliderLabel                  matlab.ui.control.Label
        ocSlider                       matlab.ui.control.Slider
        tcSliderLabel                  matlab.ui.control.Label
        tcSlider                       matlab.ui.control.Slider
        Label_11                       matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        Label_12                       matlab.ui.control.Label
        Label_13                       matlab.ui.control.Label
        Slider_2                       matlab.ui.control.Slider
        tLabel                         matlab.ui.control.Label
        LogdetailsTextAreaLabel        matlab.ui.control.Label
        LogdetailsTextArea             matlab.ui.control.TextArea
    end


    properties (Access = public)
            FileName_T = CreateLogFile('Turbine');
            FileName_C = CreateLogFile('Compressor');
    end

    methods (Access = private)
        
        function plotting_T(app)
            % Save angles
            InletMetalAngle = app.InletMetalAngleSlider_T.Value;
            OutletMetalAngle = app.OutletMetalAngleSlider_T.Value;
            Stagger = app.StaggerAngleSlider_T.Value;
              
            % Obtain Camber Line
            Camber = CamberLine(InletMetalAngle,OutletMetalAngle,Stagger, 'T');

            % Obtain extra plots
            Extras = PlottingExtras(InletMetalAngle, OutletMetalAngle, Stagger, Camber(2,1001));
            Curve = Extras{2};
            Line1 = Extras{1};
            Line2 = Extras{3};
            Line3 = Extras{4};
            Curve2 = Extras{5};
            Curve3 = Extras{6};
            
            % Emergency messages - check them
%             if InletCamberAngle - Stagger < 0
%                message = 'Inlet Camber Angle is significantly small and/or Stagger is significantly large. Please, consider changing these values!';
%                uialert(app.BladeDesigner,message,'Recommendation','Icon','info');
%             end
            
%             if OutletCamberAngle + Stagger >= 90
%                message = 'Outlet Camber Angle and/or is significantly large. Please, consider changing these values!';
%                uialert(app.BladeDesigner,message,'Recommendation','Icon','info');
%             end
            
            % Obtain plots
                % Camber Line
            plot(app.CamberAxes_T,Camber(1,:),Camber(2,:))
            hold(app.CamberAxes_T,'on')
%              pan(app.CamberAxes_T,'on');
%             zoom(app.CamberAxes_T,'on');
                % Inlet Metal Arc
            plot(app.CamberAxes_T,Curve(1,:),Curve(2,:))
            hold(app.CamberAxes_T,'on')
                % Outlet Metal Arc
            plot(app.CamberAxes_T,Curve2(1,:),Curve2(2,:))
            hold(app.CamberAxes_T,'on')
                % Tangent line at inlet
            plot(app.CamberAxes_T,Line1(1,:),Line1(2,:))
            hold(app.CamberAxes_T,'on')
                % Horizontal line at outlet
            plot(app.CamberAxes_T,Line2(1,:),Line2(2,:))
            hold(app.CamberAxes_T,'on')
                % Tangent line at outlet
            plot(app.CamberAxes_T,Line3(1,:),Line3(2,:))
            hold(app.CamberAxes_T,'on')
                % Camber Angle Arc
            plot(app.CamberAxes_T,Curve3(1,:),Curve3(2,:))
            hold(app.CamberAxes_T,'off')
                % Print angles symbols
            t1 = text(app.CamberAxes_T,Curve(1,500)-0.03,Curve(2,500)-0.03,'\chi_{1}','HorizontalAlignment','left' );
            t2 = text(app.CamberAxes_T,Curve2(1,500)+0.03,Curve2(2,500)-0.03,'\chi_{2}','HorizontalAlignment','left' );
            t3 = text(app.CamberAxes_T,Curve3(1,1000)+0.03,Curve3(2,1000)-0.03,'\theta','HorizontalAlignment','left' );
            a1 = 14;
            a3 = 'bold';
            a4 = 'red';
            t1.FontSize = a1;
            t1.FontWeight = a3;
            t1.Color = a4;
            t2.FontSize = a1;
            t2.FontWeight = a3;
            t2.Color = a4;
            t3.FontSize = a1;
            t3.FontWeight = a3;
            t3.Color = a4;
        end
        
        function plotting_C(app)
            % Save angles
            InletMetalAngle = app.InletMetalAngleSlider_C.Value;
            OutletMetalAngle = app.OutletMetalAngleSlider_C.Value;
            Stagger = app.StaggerAngleSlider_C.Value;
            
            
            % CamberLine
            Camber = CamberLine(InletMetalAngle,OutletMetalAngle,Stagger, 'C');
            
            % Obtain extra plots
            Extras = PlottingExtrasC(InletMetalAngle, OutletMetalAngle, Stagger, Camber(2,1001));
            Curve = Extras{2};
            Line1 = Extras{1};
            Line2 = Extras{3};
            Line3 = Extras{4};
            Curve2 = Extras{5};
            Curve3 = Extras{6};
            
            % Plots
            plot(app.CamberAxes_C,Camber(1,:),Camber(2,:))
            hold(app.CamberAxes_C,'on')
            % Inlet Metal Arc
            plot(app.CamberAxes_C,Curve(1,:),Curve(2,:))
            hold(app.CamberAxes_C,'on')
                % Outlet Metal Arc
            plot(app.CamberAxes_C,Curve2(1,:),Curve2(2,:))
            hold(app.CamberAxes_C,'on')
                % Tangent line at inlet
            plot(app.CamberAxes_C,Line1(1,:),Line1(2,:))
            hold(app.CamberAxes_C,'on')
                % Horizontal line at outlet
            plot(app.CamberAxes_C,Line2(1,:),Line2(2,:))
            hold(app.CamberAxes_C,'on')
                % Tangent line at outlet
            plot(app.CamberAxes_C,Line3(1,:),Line3(2,:))
            hold(app.CamberAxes_C,'on')
                % Camber Angle Arc
            plot(app.CamberAxes_C,Curve3(1,:),Curve3(2,:))
            hold(app.CamberAxes_C,'off')
            
             % Print angles symbols
            t1 = text(app.CamberAxes_C,Curve(1,500)-0.03,Curve(2,500)-0.03,'\chi_{1}','HorizontalAlignment','left' );
            t2 = text(app.CamberAxes_C,Curve2(1,500)+0.03,Curve2(2,500)-0.03,'\chi_{2}','HorizontalAlignment','left' );
            t3 = text(app.CamberAxes_C,Curve3(1,1000)+0.03,Curve3(2,1000)-0.03,'\theta','HorizontalAlignment','left' );
            a1 = 14;
            a3 = 'bold';
            a4 = 'red';
            t1.FontSize = a1;
            t1.FontWeight = a3;
            t1.Color = a4;
            t2.FontSize = a1;
            t2.FontWeight = a3;
            t2.Color = a4;
            t3.FontSize = a1;
            t3.FontWeight = a3;
            t3.Color = a4;
        end
        
        function plotting2_T(app)
            % Load Angles
            InletMetalAngle = app.InletMetalAngleSlider_T.Value;
            OutletMetalAngle = app.OutletMetalAngleSlider_T.Value;
            Stagger = app.StaggerAngleSlider_T.Value;
            
            
            % Get Camber and Blade Profiles
            Camber = CamberLine(InletMetalAngle,OutletMetalAngle,Stagger,'T');
            Blade = BladeProfile(app.ThicknessOptions_T.Value, app.MaxThicknessValue_T.Value, Camber(1,:), Camber(2,:), Stagger, 'T');
            
            % Plot
            plot(app.BladeAxes_T,Blade{1,:},Blade{2,:})
            hold(app.BladeAxes_T,'on')
            plot(app.BladeAxes_T,Camber(1,:),Camber(2,:))
            hold(app.BladeAxes_T,'off')
%             pan(app.BladeAxes_T,'on');
%             zoom(app.BladeAxes_T,'on');
            plot(app.ThicknessAxes_T,Blade{3,:},Blade{4,:})
%             pan(app.ThicknessAxes_T,'on');
%             zoom(app.ThicknessAxes_T,'on');
        end
        
        function plotting2_C(app)
            % Load Angles
            InletMetalAngle = app.InletMetalAngleSlider_C.Value;
            OutletMetalAngle = app.OutletMetalAngleSlider_C.Value;
            Stagger = app.StaggerAngleSlider_C.Value;
            
            
            % Get Camber and Blade Profiles
            Camber = CamberLine(InletMetalAngle,OutletMetalAngle,Stagger,'C');
            Blade = BladeProfile(app.ThicknessOptions_T.Value, app.MaxThicknessValue_C.Value, Camber(1,:), Camber(2,:), Stagger, 'C');
            
            % Plot
            plot(app.BladeAxes_C,Blade{1,:},Blade{2,:})
            hold(app.BladeAxes_C,'on')
            plot(app.BladeAxes_C,Camber(1,:),Camber(2,:))
            hold(app.BladeAxes_C,'off')
%             pan(app.BladeAxes_T,'on');
%             zoom(app.BladeAxes_T,'on');
            plot(app.ThicknessAxes_C,Blade{3,:},Blade{4,:})
%             pan(app.ThicknessAxes_T,'on');
%             zoom(app.ThicknessAxes_T,'on');
        end
        
        function values_angles_T(app)
            Metal1 = app.InletMetalAngleSlider_T.Value;
            Metal2 = app.OutletMetalAngleSlider_T.Value;
            Stagger = app.StaggerAngleSlider_T.Value;
            
            Camber1 = Metal1 + Stagger;
            Camber2 = Metal2 - Stagger;
            
            app.Camber1Value_T.Value = strcat(num2str(Camber1),char(176));
            
            app.Camber2Value_T.Value = strcat(num2str(Camber2),char(176));
            
            CamberAngle = Metal1+Metal2;
            app.CamberAngleValue_T.Value = strcat(num2str(CamberAngle),char(176));
        end
        
        function values_angles_C(app)
            Metal1 = app.InletMetalAngleSlider_C.Value;
            Metal2 = app.OutletMetalAngleSlider_C.Value;
            Stagger = app.StaggerAngleSlider_C.Value;
            
            Camber1 = Metal1 - Stagger;
            Camber2 = Stagger - Metal2;
            
            app.Camber1Value_C.Value = strcat(num2str(Camber1),char(176));
            
            app.Camber2Value_C.Value = strcat(num2str(Camber2),char(176));
            
            CamberAngle = Camber1 + Camber2;
            app.CamberAngleValue_C.Value = strcat(num2str(CamberAngle),char(176));
        end
        
        function thickness_T(app)
            Options = importdata('.\ThicknessProfiles\ThicknessProfilesTurbine.dat');
            app.ThicknessOptions_T.Items = Options;
            thickness2_T(app)
        end
        
        function thickness_C(app)
            Options = importdata('.\ThicknessProfiles\ThicknessProfilesCompressor.dat');
            app.ThicknessOptions_C.Items = Options;
            thickness2_C(app)
        end
            
        function thickness2_T(app)
            Max_T = MaxThickness(app.ThicknessOptions_T.Value);
            app.MaximumThicknessProfile_T.Value = Max_T(2);
            if app.MaxThicknessSlider_T.Value<0
               text = sprintf('%5.2f %%',app.MaxThicknessSlider_T.Value);
            elseif app.MaxThicknessSlider_T.Value>0
               text = sprintf('%5.2f %%',app.MaxThicknessSlider_T.Value);
            else
                text = '0%';
            end
            app.MaxThicknessSliderValue_T.Value = text;
            app.MaxThicknessValue_T.Value = (1+app.MaxThicknessSlider_T.Value/100)*Max_T(2)/100;
            plotting_T(app);
            plotting2_T(app);
        end

        function thickness2_C(app)
            Max_T = MaxThickness(app.ThicknessOptions_C.Value);
            app.MaximumThicknessProfile_C.Value = Max_T(2);
            if app.MaxThicknessSlider_C.Value<0
               text = sprintf('%5.2f %%',app.MaxThicknessSlider_C.Value);
            elseif app.MaxThicknessSlider_C.Value>0
               text = sprintf('%5.2f %%',app.MaxThicknessSlider_C.Value);
            else
                text = '0%';
            end
            app.MaxThicknessSliderValue_C.Value = text;
            app.MaxThicknessValue_C.Value = (1+app.MaxThicknessSlider_C.Value/100)*Max_T(2)/100;
            plotting_C(app);
            plotting2_C(app);
        end
        
        function LogUpload_T(app)
           fileID = fopen(app.FileName_T);
           tline = fgetl(fileID);
           i = 1;
           Value{i} = sprintf('%s\n',tline);
           while ischar(tline)
              tline = fgetl(fileID);
              if tline ==-1
                  break;
              else
                  i=i+1;
                  Value{i} = sprintf('%s\n',tline);
              end
           end
           fclose(fileID);
           app.Log_T.Value = Value;
           app.Log_C.Value = Value;

        end
        
        function LogUpload_C(app)
           fileID = fopen(app.FileName_C);
           tline = fgetl(fileID);
           i = 1;
           Value{i} = sprintf('%s\n',tline);
           while ischar(tline)
              tline = fgetl(fileID);
              if tline ==-1
                  break;
              else
                  i=i+1;
                  Value{i} = sprintf('%s\n',tline);
              end
           end
           fclose(fileID);
           app.Log_C.Value = Value;

        end
        
end      


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Options_T = importdata('.\ThicknessProfiles\ThicknessProfilesTurbine.dat');
            app.ThicknessOptions_T.Items = Options_T;
            app.ThicknessOptions_T.Value = app.ThicknessOptions_T.Items{1};
            thickness2_T(app);
            
            Options_C = importdata('.\ThicknessProfiles\ThicknessProfilesCompressor.dat');
            app.ThicknessOptions_C.Items = Options_C;
            app.ThicknessOptions_C.Value = app.ThicknessOptions_C.Items{1};
            thickness2_C(app);
        end

        % Button pushed function: AddthickprofileButton_C
        function AddthickprofileButton_CPushed(app, event)
            run('ThicknessProfile.mlapp');
        end

        % Button pushed function: AddthickprofileButton_T
        function AddthickprofileButton_TPushed(app, event)
            run('ThicknessProfile.mlapp');
        end

        % Button pushed function: DeleteprofileButton_C
        function DeleteprofileButton_CPushed(app, event)
            type = 'Compressor';
            ProfileName = app.ThicknessOptions_C.Value;
            Message = DeleteProfile(ProfileName,type);
            uialert(app.BladeDesigner,Message,'File uploaded','Icon','../Images/ok.png')
            thickness_C(app)
        end

        % Button pushed function: DeleteprofileButton_T
        function DeleteprofileButton_TPushed(app, event)
            type = 'Turbine';
            ProfileName = app.ThicknessOptions_T.Value;
            Message = DeleteProfile(ProfileName,type);
            uialert(app.BladeDesigner,Message,'File uploaded','Icon','../Images/ok.png')
            thickness_T(app)
        end

        % Value changed function: InletMetalAngleSlider_C
        function InletMetalAngleSlider_CValueChanged(app, event)
            InletMetalAngle_C = app.InletMetalAngleSlider_C.Value;
            app.InletMetalAngleValue_C.Value = strcat(num2str(InletMetalAngle_C), char(176));
            Text = ['Maximum Thickness has been changed to ',num2str(app.MaxThicknessValue_C.Value)];
            plotting_C(app);
            plotting2_C(app);
            thickness_C(app);
            values_angles_C(app);
            LogFile(app.FileName_C, Text);
            LogUpload_C(app);
        end

        % Value changed function: InletMetalAngleSlider_T
        function InletMetalAngleSlider_TValueChanged(app, event)
            InletMetalAngle = app.InletMetalAngleSlider_T.Value;
            app.InletMetalAngleValue_T.Value = strcat(num2str(InletMetalAngle), char(176));
            Text = ['Inlet Metal Angle has been changed to ',app.InletMetalAngleValue_T.Value];
            LogFile(app.FileName_T, Text);
            LogUpload_T(app);   
            plotting_T(app)
            plotting2_T(app)
            values_angles_T(app)
            thickness_T(app)
        end

        % Callback function
        function LogValueChanged(app, event)
            
        end

        % Value changed function: MaxThicknessSliderValue_C
        function MaxThicknessSliderValue_CValueChanged(app, event)
            thickness_C(app);
            
        end

        % Value changed function: MaxThicknessSliderValue_T
        function MaxThicknessSliderValue_TValueChanged(app, event)
            thickness_T(app)
        end

        % Value changed function: MaxThicknessSlider_C
        function MaxThicknessSlider_CValueChanged(app, event)
            thickness_C(app)
            Text = ['Maximum Thickness has been changed to ',num2str(app.MaxThicknessValue_C.Value)];
            LogFile(app.FileName_C, Text);
            LogUpload_C(app);
        end

        % Value changed function: MaxThicknessSlider_T
        function MaxThicknessSlider_TValueChanged(app, event)
            thickness_T(app)
            Text = ['Maximum Thickness has been changed to ',num2str(app.MaxThicknessValue_T.Value)];
            LogFile(app.FileName_T, Text);
            LogUpload_T(app);   
        end

        % Value changed function: OutletMetalAngleSlider_C
        function OutletMetalAngleSlider_CValueChanged(app, event)
            OutletMetalAngle = app.OutletMetalAngleSlider_C.Value;
            app.OutletMetalAngleValue_C.Value = strcat(num2str(OutletMetalAngle), char(176));
            Text = ['Outlet Metal Angle has been changed to ',app.OutletMetalAngleValue_C.Value];
            plotting_C(app);
            plotting2_C(app);
            thickness_C(app);
            values_angles_C(app);
            LogFile(app.FileName_C, Text);
            LogUpload_C(app);
        end

        % Value changed function: OutletMetalAngleSlider_T
        function OutletMetalAngleSlider_TValueChanged(app, event)
            OutletMetalAngle = app.OutletMetalAngleSlider_T.Value;
            app.OutletMetalAngleValue_T.Value = strcat(num2str(OutletMetalAngle), char(176));
            Text = ['Outlet Metal Angle has been changed to ',app.OutletMetalAngleValue_T.Value];
            LogFile(app.FileName_T, Text);
            LogUpload_T(app);
            plotting_T(app)
            plotting2_T(app)
            values_angles_T(app)
            thickness_T(app)
        end

        % Value changed function: StaggerAngleSlider_C
        function StaggerAngleSlider_CValueChanged(app, event)
            Stagger = app.StaggerAngleSlider_C.Value;
            app.StaggerAngleValue_C.Value = strcat(num2str(Stagger), char(176));
            Text = ['Stagger Angle has been changed to ',app.StaggerAngleValue_C.Value];
            plotting_C(app);
            plotting2_C(app);
            thickness_C(app);
            values_angles_C(app);
            LogFile(app.FileName_C, Text);
            LogUpload_C(app);
        end

        % Value changed function: StaggerAngleSlider_T
        function StaggerAngleSlider_TValueChanged(app, event)
            Stagger = app.StaggerAngleSlider_T.Value;
            app.StaggerAngleValue_T.Value = strcat(num2str(Stagger), char(176));
            Text = ['Stagger Angle has been changed to ',app.StaggerAngleValue_T.Value];
            LogFile(app.FileName_T, Text);
            LogUpload_T(app);
            plotting_T(app)
            plotting2_T(app)
            values_angles_T(app)
            thickness_T(app)
        end

        % Value changed function: ThicknessOptions_C
        function ThicknessOptions_CValueChanged(app, event)
            plotting_C(app)
            plotting2_C(app)
            thickness_C(app)
            Text = ['Thickness Profile has been changed to ',app.ThicknessOptions_C.Value];
            LogFile(app.FileName_C, Text);
            LogUpload_C(app);
        end

        % Value changed function: ThicknessOptions_T
        function ThicknessOptions_TValueChanged(app, event)
            plotting_T(app)
            plotting2_T(app)
            thickness_T(app)
            Text = ['Thickness Profile has been changed to ',app.ThicknessOptions_T.Value];
            LogFile(app.FileName_T, Text);
            LogUpload_T(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BladeDesigner = uifigure;
            app.BladeDesigner.Name = 'UI Figure';
            app.BladeDesignerBetaVersionLabel = uilabel(app.BladeDesigner);
            app.BladeDesignerBetaVersionLabel.Text = 'Blade Designer Beta Version';
            app.Selection = uitabgroup(app.BladeDesigner);
            app.Turbine = uitab(app.Selection);
            app.Turbine.Title = 'Turbine';
            app.CamberAxes_T = uiaxes(app.Turbine);
            title(app.CamberAxes_T, 'Camber Line')
            xlabel(app.CamberAxes_T, 'X')
            ylabel(app.CamberAxes_T, 'Y')
            app.CamberAxes_T.XAxisLocation = 'origin';
            app.CamberAxes_T.YAxisLocation = 'origin';
            app.ThicknessAxes_T = uiaxes(app.Turbine);
            title(app.ThicknessAxes_T, 'Thickness Profile')
            xlabel(app.ThicknessAxes_T, 'X')
            ylabel(app.ThicknessAxes_T, 'Y')
            app.ThicknessAxes_T.XAxisLocation = 'origin';
            app.ThicknessAxes_T.YAxisLocation = 'origin';
            app.BladeAxes_T = uiaxes(app.Turbine);
            title(app.BladeAxes_T, 'Blade Profile')
            xlabel(app.BladeAxes_T, 'X')
            ylabel(app.BladeAxes_T, 'Y')
            app.BladeAxes_T.XAxisLocation = 'origin';
            app.BladeAxes_T.XTick = [0 0.5 1 1.5 2];
            app.BladeAxes_T.YAxisLocation = 'origin';
            app.BladeAxes_T.YTick = [-0.5 0 0.5 1 1.5];
            app.InletMetalAngleSlider_T = uislider(app.Turbine);
            app.InletMetalAngleSlider_T.Limits = [0 90];
            app.InletMetalAngleSlider_T.ValueChangedFcn = createCallbackFcn(app, @InletMetalAngleSlider_TValueChanged, true);
            app.OutletMetalAngleSliderLabel_T = uilabel(app.Turbine);
            app.OutletMetalAngleSliderLabel_T.Text = 'Outlet Metal Angle';
            app.OutletMetalAngleSlider_T = uislider(app.Turbine);
            app.OutletMetalAngleSlider_T.Limits = [0 90];
            app.OutletMetalAngleSlider_T.ValueChangedFcn = createCallbackFcn(app, @OutletMetalAngleSlider_TValueChanged, true);
            app.InletMetalAngleSliderLabel_T = uilabel(app.Turbine);
            app.InletMetalAngleSliderLabel_T.Text = 'Inlet Metal Angle';
            app.StaggerAngleSlider_T = uislider(app.Turbine);
            app.StaggerAngleSlider_T.Limits = [0 90];
            app.StaggerAngleSlider_T.ValueChangedFcn = createCallbackFcn(app, @StaggerAngleSlider_TValueChanged, true);
            app.StaggerAngleSliderLabel_T = uilabel(app.Turbine);
            app.StaggerAngleSliderLabel_T.Text = 'Stagger Angle';
            app.ChoosebladethicknessprofileLabel_T = uilabel(app.Turbine);
            app.ChoosebladethicknessprofileLabel_T.Text = 'Choose blade thickness profile';
            app.LogdetailsLabel_T = uilabel(app.Turbine);
            app.LogdetailsLabel_T.Text = 'Log details';
            app.ThicknessOptions_T = uilistbox(app.Turbine);
            app.ThicknessOptions_T.Items = {};
            app.ThicknessOptions_T.ValueChangedFcn = createCallbackFcn(app, @ThicknessOptions_TValueChanged, true);
            app.ThicknessOptions_T.Value = {};
            app.InletMetalAngleValue_T = uieditfield(app.Turbine, 'text');
            app.InletMetalAngleValue_T.Editable = 'off';
            app.OutletMetalAngleValue_T = uieditfield(app.Turbine, 'text');
            app.OutletMetalAngleValue_T.Editable = 'off';
            app.StaggerAngleValue_T = uieditfield(app.Turbine, 'text');
            app.StaggerAngleValue_T.Editable = 'off';
            app.AddthickprofileButton_T = uibutton(app.Turbine, 'push');
            app.AddthickprofileButton_T.ButtonPushedFcn = createCallbackFcn(app, @AddthickprofileButton_TPushed, true);
            app.AddthickprofileButton_T.Text = 'Add thick profile';
            app.CamberLineAnglesLabel_T = uilabel(app.Turbine);
            app.CamberLineAnglesLabel_T.Text = 'Camber Line Angles';
            app.Label = uilabel(app.Turbine);
            app.Label.Text = 'θ';
            app.Camber1Value_T = uieditfield(app.Turbine, 'text');
            app.Camber1Value_T.Editable = 'off';
            app.Label_3 = uilabel(app.Turbine);
            app.Label_3.Text = 'θ';
            app.Camber2Value_T = uieditfield(app.Turbine, 'text');
            app.Camber2Value_T.Editable = 'off';
            app.Label_2 = uilabel(app.Turbine);
            app.Label_2.Text = 'θ';
            app.CamberAngleValue_T = uieditfield(app.Turbine, 'text');
            app.CamberAngleValue_T.Editable = 'off';
            app.Label_4 = uilabel(app.Turbine);
            app.Label_4.Text = '1';
            app.Label_5 = uilabel(app.Turbine);
            app.Label_5.Text = '2';
            app.DeleteprofileButton_T = uibutton(app.Turbine, 'push');
            app.DeleteprofileButton_T.ButtonPushedFcn = createCallbackFcn(app, @DeleteprofileButton_TPushed, true);
            app.DeleteprofileButton_T.Text = 'Delete profile';
            app.IncreaseordecreasethemaximumthicknessLabel_T = uilabel(app.Turbine);
            app.IncreaseordecreasethemaximumthicknessLabel_T.Text = 'Increase or decrease the maximum thickness';
            app.MaximumThicknessoftheProfileLabel = uilabel(app.Turbine);
            app.MaximumThicknessoftheProfileLabel.Text = 'Maximum Thickness of the Profile (%)';
            app.MaximumThicknessProfile_T = uieditfield(app.Turbine, 'numeric');
            app.MaximumThicknessProfile_T.Editable = 'off';
            app.MaxThicknessSlider_T = uislider(app.Turbine);
            app.MaxThicknessSlider_T.Limits = [-100 100];
            app.MaxThicknessSlider_T.ValueChangedFcn = createCallbackFcn(app, @MaxThicknessSlider_TValueChanged, true);
            app.MaxThicknessValue_T = uieditfield(app.Turbine, 'numeric');
            app.MaxThicknessValue_T.ValueDisplayFormat = '%.3f';
            app.MaxThicknessValue_T.Editable = 'off';
            app.MaxThicknessSliderValue_T = uieditfield(app.Turbine, 'text');
            app.MaxThicknessSliderValue_T.ValueChangedFcn = createCallbackFcn(app, @MaxThicknessSliderValue_TValueChanged, true);
            app.MaxThicknessSliderValue_T.Editable = 'off';
            app.Log_T = uitextarea(app.Turbine);
            app.Log_T.Editable = 'off';
            app.Log_T.Value = {''; ''};
            app.CompressorTab = uitab(app.Selection);
            app.CompressorTab.Title = 'Compressor';
            app.CamberAxes_C = uiaxes(app.CompressorTab);
            title(app.CamberAxes_C, 'Camber Line')
            xlabel(app.CamberAxes_C, 'X')
            ylabel(app.CamberAxes_C, 'Y')
            app.CamberAxes_C.CLim = [0 1];
            app.CamberAxes_C.CLimMode = 'manual';
            app.CamberAxes_C.XAxisLocation = 'origin';
            app.CamberAxes_C.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.CamberAxes_C.YAxisLocation = 'origin';
            app.CamberAxes_C.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.ThicknessAxes_C = uiaxes(app.CompressorTab);
            title(app.ThicknessAxes_C, 'Thickness Profile')
            xlabel(app.ThicknessAxes_C, 'X')
            ylabel(app.ThicknessAxes_C, 'Y')
            app.ThicknessAxes_C.CLim = [0 1];
            app.ThicknessAxes_C.CLimMode = 'manual';
            app.ThicknessAxes_C.XAxisLocation = 'origin';
            app.ThicknessAxes_C.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.ThicknessAxes_C.YAxisLocation = 'origin';
            app.ThicknessAxes_C.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.BladeAxes_C = uiaxes(app.CompressorTab);
            title(app.BladeAxes_C, 'Blade Profile')
            xlabel(app.BladeAxes_C, 'X')
            ylabel(app.BladeAxes_C, 'Y')
            app.BladeAxes_C.CLim = [0 1];
            app.BladeAxes_C.CLimMode = 'manual';
            app.BladeAxes_C.XAxisLocation = 'origin';
            app.BladeAxes_C.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.BladeAxes_C.YAxisLocation = 'origin';
            app.BladeAxes_C.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.InletMetalAngleSlider_C = uislider(app.CompressorTab);
            app.InletMetalAngleSlider_C.Limits = [0 90];
            app.InletMetalAngleSlider_C.ValueChangedFcn = createCallbackFcn(app, @InletMetalAngleSlider_CValueChanged, true);
            app.OutletMetalAngleSliderLabel_C = uilabel(app.CompressorTab);
            app.OutletMetalAngleSliderLabel_C.Text = 'Outlet Metal Angle';
            app.OutletMetalAngleSlider_C = uislider(app.CompressorTab);
            app.OutletMetalAngleSlider_C.Limits = [0 90];
            app.OutletMetalAngleSlider_C.ValueChangedFcn = createCallbackFcn(app, @OutletMetalAngleSlider_CValueChanged, true);
            app.InletMetalAngleSliderLabel_C = uilabel(app.CompressorTab);
            app.InletMetalAngleSliderLabel_C.Text = 'Inlet Metal Angle';
            app.StaggerAngleSlider_C = uislider(app.CompressorTab);
            app.StaggerAngleSlider_C.Limits = [0 90];
            app.StaggerAngleSlider_C.ValueChangedFcn = createCallbackFcn(app, @StaggerAngleSlider_CValueChanged, true);
            app.StaggerAngleSliderLabel_C = uilabel(app.CompressorTab);
            app.StaggerAngleSliderLabel_C.Text = 'Stagger Angle';
            app.InletMetalAngleValue_C = uieditfield(app.CompressorTab, 'text');
            app.InletMetalAngleValue_C.Editable = 'off';
            app.OutletMetalAngleValue_C = uieditfield(app.CompressorTab, 'text');
            app.OutletMetalAngleValue_C.Editable = 'off';
            app.StaggerAngleValue_C = uieditfield(app.CompressorTab, 'text');
            app.StaggerAngleValue_C.Editable = 'off';
            app.CamberLineAnglesLabel_C = uilabel(app.CompressorTab);
            app.CamberLineAnglesLabel_C.Text = 'Camber Line Angles';
            app.Label_6 = uilabel(app.CompressorTab);
            app.Label_6.Text = 'θ';
            app.Camber1Value_C = uieditfield(app.CompressorTab, 'text');
            app.Camber1Value_C.Editable = 'off';
            app.Label_7 = uilabel(app.CompressorTab);
            app.Label_7.Text = 'θ';
            app.Camber2Value_C = uieditfield(app.CompressorTab, 'text');
            app.Camber2Value_C.Editable = 'off';
            app.Label_8 = uilabel(app.CompressorTab);
            app.Label_8.Text = 'θ';
            app.CamberAngleValue_C = uieditfield(app.CompressorTab, 'text');
            app.CamberAngleValue_C.Editable = 'off';
            app.ChoosebladethicknessprofileLabel_C = uilabel(app.CompressorTab);
            app.ChoosebladethicknessprofileLabel_C.Text = 'Choose blade thickness profile';
            app.ThicknessOptions_C = uilistbox(app.CompressorTab);
            app.ThicknessOptions_C.Items = {};
            app.ThicknessOptions_C.ValueChangedFcn = createCallbackFcn(app, @ThicknessOptions_CValueChanged, true);
            app.ThicknessOptions_C.Value = {};
            app.AddthickprofileButton_C = uibutton(app.CompressorTab, 'push');
            app.AddthickprofileButton_C.ButtonPushedFcn = createCallbackFcn(app, @AddthickprofileButton_CPushed, true);
            app.AddthickprofileButton_C.Text = 'Add thick profile';
            app.DeleteprofileButton_C = uibutton(app.CompressorTab, 'push');
            app.DeleteprofileButton_C.ButtonPushedFcn = createCallbackFcn(app, @DeleteprofileButton_CPushed, true);
            app.DeleteprofileButton_C.Text = 'Delete profile';
            app.IncreaseordecreasethemaximumthicknessLabel_C = uilabel(app.CompressorTab);
            app.IncreaseordecreasethemaximumthicknessLabel_C.Text = 'Increase or decrease the maximum thickness';
            app.MaximumThicknessoftheProfileLabel_C = uilabel(app.CompressorTab);
            app.MaximumThicknessoftheProfileLabel_C.Text = 'Maximum Thickness of the Profile (%)';
            app.MaximumThicknessProfile_C = uieditfield(app.CompressorTab, 'numeric');
            app.MaximumThicknessProfile_C.Editable = 'off';
            app.MaxThicknessSlider_C = uislider(app.CompressorTab);
            app.MaxThicknessSlider_C.Limits = [-100 100];
            app.MaxThicknessSlider_C.ValueChangedFcn = createCallbackFcn(app, @MaxThicknessSlider_CValueChanged, true);
            app.MaxThicknessValue_C = uieditfield(app.CompressorTab, 'numeric');
            app.MaxThicknessValue_C.ValueDisplayFormat = '%.3f';
            app.MaxThicknessValue_C.Editable = 'off';
            app.MaxThicknessSliderValue_C = uieditfield(app.CompressorTab, 'text');
            app.MaxThicknessSliderValue_C.ValueChangedFcn = createCallbackFcn(app, @MaxThicknessSliderValue_CValueChanged, true);
            app.MaxThicknessSliderValue_C.Editable = 'off';
            app.LogdetailsLabel_C = uilabel(app.CompressorTab);
            app.LogdetailsLabel_C.Text = 'Log details';
            app.Log_C = uitextarea(app.CompressorTab);
            app.Log_C.Editable = 'off';
            app.Log_C.Value = {''; ''};
            app.Label_9 = uilabel(app.CompressorTab);
            app.Label_9.Text = '1';
            app.Label_10 = uilabel(app.CompressorTab);
            app.Label_10.Text = '2';
            app.ManualTab = uitab(app.Selection);
            app.ManualTab.Title = 'Manual';
            app.LeadingEdgeParametersLabel = uilabel(app.ManualTab);
            app.LeadingEdgeParametersLabel.Text = 'Leading Edge Parameters';
            app.SemimajoraxisSliderLabel = uilabel(app.ManualTab);
            app.SemimajoraxisSliderLabel.Text = 'Semimajor axis';
            app.SemimajoraxisSlider = uislider(app.ManualTab);
            app.SemimajoraxisSlider.Limits = [0 0.1];
            app.SemiminoraxisSliderLabel = uilabel(app.ManualTab);
            app.SemiminoraxisSliderLabel.Text = 'Semiminor axis';
            app.SemiminoraxisSlider = uislider(app.ManualTab);
            app.SemiminoraxisSlider.Limits = [0 0.1];
            app.InletMetalSliderLabel = uilabel(app.ManualTab);
            app.InletMetalSliderLabel.Text = 'Inlet Metal';
            app.InletMetalSlider = uislider(app.ManualTab);
            app.InletMetalSlider.Limits = [0 90];
            app.SSangleSliderLabel = uilabel(app.ManualTab);
            app.SSangleSliderLabel.Text = 'SS angle';
            app.SSangleSlider = uislider(app.ManualTab);
            app.SSangleSlider.Limits = [0 90];
            app.PSangleSliderLabel = uilabel(app.ManualTab);
            app.PSangleSliderLabel.Text = 'PS angle';
            app.PSangleSlider = uislider(app.ManualTab);
            app.PSangleSlider.Limits = [0 90];
            app.TrailingEdgeParametersLabel = uilabel(app.ManualTab);
            app.TrailingEdgeParametersLabel.Text = 'Trailing Edge Parameters';
            app.InletMetalSliderLabel_2 = uilabel(app.ManualTab);
            app.InletMetalSliderLabel_2.Text = 'Inlet Metal';
            app.InletMetalSlider_2 = uislider(app.ManualTab);
            app.InletMetalSlider_2.Limits = [0 90];
            app.SSangleSliderLabel_2 = uilabel(app.ManualTab);
            app.SSangleSliderLabel_2.Text = 'SS angle';
            app.SSangleSlider_2 = uislider(app.ManualTab);
            app.SSangleSlider_2.Limits = [0 90];
            app.PSangleSliderLabel_2 = uilabel(app.ManualTab);
            app.PSangleSliderLabel_2.Text = 'PS angle';
            app.PSangleSlider_2 = uislider(app.ManualTab);
            app.PSangleSlider_2.Limits = [0 90];
            app.SemimajoraxisSliderLabel_2 = uilabel(app.ManualTab);
            app.SemimajoraxisSliderLabel_2.Text = 'Radius';
            app.SemimajoraxisSlider_2 = uislider(app.ManualTab);
            app.SemimajoraxisSlider_2.Limits = [0 0.1];
            app.ProfileparametersLabel = uilabel(app.ManualTab);
            app.ProfileparametersLabel.Text = 'Profile parameters';
            app.StaggerSliderLabel = uilabel(app.ManualTab);
            app.StaggerSliderLabel.Text = 'Stagger';
            app.StaggerSlider = uislider(app.ManualTab);
            app.StaggerSlider.Limits = [0 90];
            app.scSliderLabel = uilabel(app.ManualTab);
            app.scSliderLabel.Text = 's/c';
            app.scSlider = uislider(app.ManualTab);
            app.scSlider.Limits = [0 1.5];
            app.ocSliderLabel = uilabel(app.ManualTab);
            app.ocSliderLabel.Text = 'o/c';
            app.ocSlider = uislider(app.ManualTab);
            app.ocSlider.Limits = [0 1];
            app.tcSliderLabel = uilabel(app.ManualTab);
            app.tcSliderLabel.Text = 't/c';
            app.tcSlider = uislider(app.ManualTab);
            app.tcSlider.Limits = [0 1];
            app.Label_11 = uilabel(app.ManualTab);
            app.Label_11.Text = 'φ';
            app.Slider = uislider(app.ManualTab);
            app.Slider.Limits = [0 90];
            app.Label_12 = uilabel(app.ManualTab);
            app.Label_12.Text = 'ο';
            app.Label_13 = uilabel(app.ManualTab);
            app.Label_13.Text = 'φ';
            app.Slider_2 = uislider(app.ManualTab);
            app.Slider_2.Limits = [0 90];
            app.tLabel = uilabel(app.ManualTab);
            app.tLabel.Text = 't';
            app.LogdetailsTextAreaLabel = uilabel(app.ManualTab);
            app.LogdetailsTextAreaLabel.Text = 'Log details';
            app.LogdetailsTextArea = uitextarea(app.ManualTab);
        end
    end

    methods (Access = public)

        % Construct app
        function app = BladeDesignerBeta

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BladeDesigner)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BladeDesigner)
        end
    end
end